classdef fNIRSapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fNIRScontrolUIFigure            matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        menuLoadSDG                     matlab.ui.container.Menu
        menuSaveSDG                     matlab.ui.container.Menu
        menuSaveData                    matlab.ui.container.Menu
        menuExit                        matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        menu_AutoSave                   matlab.ui.container.Menu
        menu_AutoSaveDirectory          matlab.ui.container.Menu
        menu_DataCollectionPeriod       matlab.ui.container.Menu
        menu_DataCollection60min        matlab.ui.container.Menu
        menu_DataCollection120min       matlab.ui.container.Menu
        menu_DataCollection180min       matlab.ui.container.Menu
        menu_DataCollection15minAuto    matlab.ui.container.Menu
        menu_DataCollection30minAuto    matlab.ui.container.Menu
        menu_DataCollection60minAuto    matlab.ui.container.Menu
        menuSDGdisplaySignalLevel       matlab.ui.container.Menu
        menuSDGdisplaySignalLevel_Threshold  matlab.ui.container.Menu
        menuSDGdisplaySignalLevel_Analog  matlab.ui.container.Menu
        menuStartKeypress               matlab.ui.container.Menu
        menuStateSwitching              matlab.ui.container.Menu
        menuLaserLinearityCalibration   matlab.ui.container.Menu
        menuSystemCharacterization      matlab.ui.container.Menu
        axisPlotData                    matlab.ui.control.UIAxes
        axesSDG                         matlab.ui.control.UIAxes
        togglebuttonStartStop           matlab.ui.control.StateButton
        editStdThresh                   matlab.ui.control.EditField
        textNoiseThresh                 matlab.ui.control.Label
        uipanelSources                  matlab.ui.container.Panel
        togglebuttonSrc5_1              matlab.ui.control.StateButton
        togglebuttonSrc5_2              matlab.ui.control.StateButton
        togglebuttonSrc6_1              matlab.ui.control.StateButton
        togglebuttonSrc6_2              matlab.ui.control.StateButton
        togglebuttonSrc7_1              matlab.ui.control.StateButton
        togglebuttonSrc7_2              matlab.ui.control.StateButton
        togglebuttonSrc8_1              matlab.ui.control.StateButton
        togglebuttonSrc8_2              matlab.ui.control.StateButton
        togglebuttonSrc1_1              matlab.ui.control.StateButton
        togglebuttonSrc1_2              matlab.ui.control.StateButton
        togglebuttonSrc2_1              matlab.ui.control.StateButton
        togglebuttonSrc2_2              matlab.ui.control.StateButton
        togglebuttonSrc3_1              matlab.ui.control.StateButton
        togglebuttonSrc3_2              matlab.ui.control.StateButton
        togglebuttonSrc4_1              matlab.ui.control.StateButton
        togglebuttonSrc4_2              matlab.ui.control.StateButton
        togglebuttonSrc13_1             matlab.ui.control.StateButton
        togglebuttonSrc13_2             matlab.ui.control.StateButton
        togglebuttonSrc14_1             matlab.ui.control.StateButton
        togglebuttonSrc14_2             matlab.ui.control.StateButton
        togglebuttonSrc15_1             matlab.ui.control.StateButton
        togglebuttonSrc15_2             matlab.ui.control.StateButton
        togglebuttonSrc16_1             matlab.ui.control.StateButton
        togglebuttonSrc16_2             matlab.ui.control.StateButton
        togglebuttonSrc9_1              matlab.ui.control.StateButton
        togglebuttonSrc9_2              matlab.ui.control.StateButton
        togglebuttonSrc10_1             matlab.ui.control.StateButton
        togglebuttonSrc10_2             matlab.ui.control.StateButton
        togglebuttonSrc11_1             matlab.ui.control.StateButton
        togglebuttonSrc11_2             matlab.ui.control.StateButton
        togglebuttonSrc12_1             matlab.ui.control.StateButton
        togglebuttonSrc12_2             matlab.ui.control.StateButton
        togglebuttonSrcOnOff            matlab.ui.control.StateButton
        editSrcPower1                   matlab.ui.control.EditField
        editSrcPower3                   matlab.ui.control.EditField
        editSrcPower5                   matlab.ui.control.EditField
        editSrcPower7                   matlab.ui.control.EditField
        editSrcPower9                   matlab.ui.control.EditField
        editSrcPower11                  matlab.ui.control.EditField
        editSrcPower13                  matlab.ui.control.EditField
        editSrcPower15                  matlab.ui.control.EditField
        editSrcPower2                   matlab.ui.control.EditField
        editSrcPower4                   matlab.ui.control.EditField
        editSrcPower6                   matlab.ui.control.EditField
        editSrcPower8                   matlab.ui.control.EditField
        editSrcPower10                  matlab.ui.control.EditField
        editSrcPower12                  matlab.ui.control.EditField
        editSrcPower14                  matlab.ui.control.EditField
        editSrcPower16                  matlab.ui.control.EditField
        editSrcPower17                  matlab.ui.control.EditField
        editSrcPower19                  matlab.ui.control.EditField
        editSrcPower21                  matlab.ui.control.EditField
        editSrcPower23                  matlab.ui.control.EditField
        editSrcPower25                  matlab.ui.control.EditField
        editSrcPower27                  matlab.ui.control.EditField
        editSrcPower29                  matlab.ui.control.EditField
        editSrcPower31                  matlab.ui.control.EditField
        editSrcPower18                  matlab.ui.control.EditField
        editSrcPower20                  matlab.ui.control.EditField
        editSrcPower22                  matlab.ui.control.EditField
        editSrcPower24                  matlab.ui.control.EditField
        editSrcPower26                  matlab.ui.control.EditField
        editSrcPower28                  matlab.ui.control.EditField
        editSrcPower30                  matlab.ui.control.EditField
        editSrcPower32                  matlab.ui.control.EditField
        textSetSrcPower                 matlab.ui.control.Label
        editSetSrcPower                 matlab.ui.control.EditField
        pushbuttonSetSrcPower           matlab.ui.control.Button
        editSetSrcPowerAll              matlab.ui.control.EditField
        textSetSrcPowerAll              matlab.ui.control.Label
        Button_1                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_9                        matlab.ui.control.StateButton
        Button_10                       matlab.ui.control.StateButton
        Button_11                       matlab.ui.control.StateButton
        Button_12                       matlab.ui.control.StateButton
        Button_13                       matlab.ui.control.StateButton
        Button_14                       matlab.ui.control.StateButton
        Button_15                       matlab.ui.control.StateButton
        Button_16                       matlab.ui.control.StateButton
        uipanelDetectors                matlab.ui.container.Panel
        editGainAll                     matlab.ui.control.EditField
        textGainAll                     matlab.ui.control.Label
        textDet1                        matlab.ui.control.Label
        textDet2                        matlab.ui.control.Label
        textDet3                        matlab.ui.control.Label
        textDet4                        matlab.ui.control.Label
        textDet5                        matlab.ui.control.Label
        textDet6                        matlab.ui.control.Label
        textDet7                        matlab.ui.control.Label
        textDet8                        matlab.ui.control.Label
        editGain1                       matlab.ui.control.EditField
        editGain2                       matlab.ui.control.EditField
        editGain3                       matlab.ui.control.EditField
        editGain4                       matlab.ui.control.EditField
        editGain5                       matlab.ui.control.EditField
        editGain6                       matlab.ui.control.EditField
        editGain7                       matlab.ui.control.EditField
        textDet9                        matlab.ui.control.Label
        textDet10                       matlab.ui.control.Label
        textDet11                       matlab.ui.control.Label
        textDet12                       matlab.ui.control.Label
        textDet13                       matlab.ui.control.Label
        textDet14                       matlab.ui.control.Label
        textDet15                       matlab.ui.control.Label
        textDet16                       matlab.ui.control.Label
        editGain9                       matlab.ui.control.EditField
        editGain10                      matlab.ui.control.EditField
        editGain11                      matlab.ui.control.EditField
        editGain12                      matlab.ui.control.EditField
        editGain13                      matlab.ui.control.EditField
        editGain14                      matlab.ui.control.EditField
        editGain15                      matlab.ui.control.EditField
        editGain16                      matlab.ui.control.EditField
        editGain8                       matlab.ui.control.EditField
        textDet17                       matlab.ui.control.Label
        textDet18                       matlab.ui.control.Label
        textDet19                       matlab.ui.control.Label
        textDet20                       matlab.ui.control.Label
        textDet21                       matlab.ui.control.Label
        textDet22                       matlab.ui.control.Label
        textDet23                       matlab.ui.control.Label
        textDet24                       matlab.ui.control.Label
        editGain17                      matlab.ui.control.EditField
        editGain18                      matlab.ui.control.EditField
        editGain19                      matlab.ui.control.EditField
        editGain20                      matlab.ui.control.EditField
        editGain21                      matlab.ui.control.EditField
        editGain22                      matlab.ui.control.EditField
        editGain23                      matlab.ui.control.EditField
        textDet25                       matlab.ui.control.Label
        textDet26                       matlab.ui.control.Label
        textDet27                       matlab.ui.control.Label
        textDet28                       matlab.ui.control.Label
        textDet29                       matlab.ui.control.Label
        textDet30                       matlab.ui.control.Label
        textDet31                       matlab.ui.control.Label
        textDet32                       matlab.ui.control.Label
        editGain25                      matlab.ui.control.EditField
        editGain26                      matlab.ui.control.EditField
        editGain27                      matlab.ui.control.EditField
        editGain28                      matlab.ui.control.EditField
        editGain29                      matlab.ui.control.EditField
        editGain30                      matlab.ui.control.EditField
        editGain31                      matlab.ui.control.EditField
        editGain32                      matlab.ui.control.EditField
        editGain24                      matlab.ui.control.EditField
        textSetGain                     matlab.ui.control.Label
        editAutoGainLevel               matlab.ui.control.EditField
        pushbuttonSetGain               matlab.ui.control.Button
        pushbuttonCheckSaturation       matlab.ui.control.Button
        togglebuttonSystemCharacterize  matlab.ui.control.StateButton
        togglebuttonReset               matlab.ui.control.Button
        uipanelStateSwitching           matlab.ui.container.ButtonGroup
        checkboxSrcSwitchEnable         matlab.ui.control.CheckBox
        radiobuttonState3               matlab.ui.control.RadioButton
        radiobuttonState1               matlab.ui.control.RadioButton
        radiobuttonState2               matlab.ui.control.RadioButton
        editSrcSwitchDwell1             matlab.ui.control.EditField
        editSrcSwitchDwell2             matlab.ui.control.EditField
        editSrcSwitchDwell3             matlab.ui.control.EditField
        textSrcSwitchDwell              matlab.ui.control.Label
        pushbuttonLoadStates            matlab.ui.control.Button
        uipanel5                        matlab.ui.container.Panel
        togglebuttonDisplayAux          matlab.ui.control.StateButton
        WavelengthdisplayListBoxLabel   matlab.ui.control.Label
        WavelengthdisplayListBox        matlab.ui.control.ListBox
        DisplayWindowsecondsEditFieldLabel  matlab.ui.control.Label
        DisplayWindowsecondsEditField   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        checkboxAutoscale               matlab.ui.control.CheckBox
        DisplaytypeButtonGroup          matlab.ui.container.ButtonGroup
        dBButton                        matlab.ui.control.RadioButton
        DeltaODButton                   matlab.ui.control.RadioButton
        HemoglobinButton                matlab.ui.control.RadioButton
        uipanelAux                      matlab.ui.container.Panel
        checkboxAux1                    matlab.ui.control.CheckBox
        checkboxAux2                    matlab.ui.control.CheckBox
        checkboxAux3                    matlab.ui.control.CheckBox
        checkboxAux4                    matlab.ui.control.CheckBox
        checkboxAux5                    matlab.ui.control.CheckBox
        checkboxAux6                    matlab.ui.control.CheckBox
        checkboxAux7                    matlab.ui.control.CheckBox
        checkboxAux8                    matlab.ui.control.CheckBox
        popupmenuAux8                   matlab.ui.control.DropDown
        popupmenuAux7                   matlab.ui.control.DropDown
        popupmenuAux6                   matlab.ui.control.DropDown
        popupmenuAux5                   matlab.ui.control.DropDown
        popupmenuAux4                   matlab.ui.control.DropDown
        popupmenuAux3                   matlab.ui.control.DropDown
        popupmenuAux2                   matlab.ui.control.DropDown
        popupmenuAux1                   matlab.ui.control.DropDown
        togglebuttonSystemCharTime      matlab.ui.control.StateButton
        pushbuttonStimMark              matlab.ui.control.Button
        textAutoGainSrcDet              matlab.ui.control.Label
        editAutoGainSrcDet              matlab.ui.control.EditField
        pushbuttonAutoGainSrcDet        matlab.ui.control.Button
        textMessage                     matlab.ui.control.Label
        textSDGfilename                 matlab.ui.control.Label
        DebugButton                     matlab.ui.control.Button
        RateEditFieldLabel              matlab.ui.control.Label
        editRate                        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        LSLstreamer  %LSL streamer
        sp % Serial port object
        devinfo=[] % Contains all relevant information of the cw6 system
        CommSet % contains all commands specific to serial communication to the hardware
        funcSet% contains all subroutines specific to the CW6 system
        cw6simul % used to simulate a CW6 system in case nothing is connected
        Hardware % String storing the hardware type
        Port    % String storing the port
        HWdialstate % Description
        DevType %String identifyin the device type
        nSD% Contents of nSD file
        LEDstate=[]; % Description
        active=0; % Boolean to specify if the app is currently doing acquisition
    end
    
    properties (Access = private)
        
        sppars% Contains the serial port parameters
        
        
        currchans % Current channels to display on plot; it's an array collecting the rows of the measurelist we wish to plot
        databuffer % Stores the acquired data
        time % Stores the time vector
        currt % Current time relative to ACQ start; used for visualization purposes
        stims % Vector to save stimmarks
        Level % stores the desired power level for all sources when they are on
        colores % defines the colors used by the app for plotting
        lineS % defines the linestyles used by the app for plotting
        nMins=60; % Number of minutes to record; should be changed by menu
        
    end
    
    
    methods (Access = private)
        
        function results = SetDevEnv(app)
            results=0;  %success flag
            funcpath=['device_functions',filesep];
            switch app.DevType
                case 'CW6'
                    addpath(genpath([funcpath,'CW6']));
                    %sets the command set, serial port parameters and
                    %function set for the hardware
                    [app.sppars,app.CommSet,app.funcSet]=setEnvCW6();
                    results=1; %success!
                otherwise
                    msgbox([app.DevType,' is not compatible with the current version of this app'])
            end
        end
        
        
        function results = drawSD(app)
            results=1; %use to indicate success or something
            nSD=app.nSD; %#ok<ADPROP>
            Nchans=size(nSD.measList,1)/nSD.nLambdas; %#ok<ADPROP> %number of channels
            
            SrcPos=nSD.srcPos; %#ok<ADPROP>
            DetPos=nSD.detPos; %#ok<ADPROP>
            nSrcs=nSD.nSrcs; %#ok<ADPROP>
            nDets=nSD.nDets; %#ok<ADPROP>
            ax=app.axesSDG;
            
            %empty axis
            cla(ax)
            set(ax,'box','off')
            set(ax,'xtick',[])
            set(ax,'xticklabel',[])
            set(ax,'ytick',[])
            set(ax,'yticklabel',[])
            set(ax,'xcolor','none')
            set(ax,'ycolor','none')
            
            hold(ax,'on')
            %draw channels as grey lines
            
            %these should be set according to "canvas size"
            lw=10; %linewidth
            ms=16; %marker size
            ds=8;  %displacement for source number
            dd=-4;  %displacement for detector number
            fs=20;  %font size for sd numbers
            
            ML=nSD.measList; %#ok<ADPROP>
            for k=1:Nchans
                x=[SrcPos(ML(k,1),1),DetPos(ML(k,2),1)];
                y=[SrcPos(ML(k,1),2),DetPos(ML(k,2),2)];
                line(ax,x,y,'Color',[.5,0.5,0.5],'LineWidth',lw,...
                    'Tag',['Cha',num2str(k)],'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
                
            end
            
            %draw sources
            for k=1:nSrcs
                %                 plot(ax,SrcPos(k,1),SrcPos(k,2),'rx','MarkerSize',16,...
                %                     'Tag','Src','ButtonDownFcn',{@SrcCallback,1,2})
                
                plot(ax,SrcPos(k,1),SrcPos(k,2),'rx','MarkerSize',ms,...
                    'Tag',['Src',num2str(k)],'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
            end
            
            %draw detectors
            for k=1:nDets
                plot(ax,DetPos(k,1),DetPos(k,2),'bo','MarkerSize',ms,...
                    'Tag',['Det',num2str(k)],'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true))
            end
            
            
            %This should be automatic: set the limits of the display area
            axis(ax,[-10,110,-40,10])
            
            
            %disable standard interactions
            disableDefaultInteractivity(ax)
            ax.Toolbar.Visible='off';
            
            %draw numbers
            for k=1:nSrcs
                text(ax,SrcPos(k,1)-ds,SrcPos(k,2),num2str(k),'Color',[1,0,0],'FontSize',fs)
            end
            for k=1:nDets
                text(ax,DetPos(k,1)-dd,DetPos(k,2),num2str(k),'Color',[0,0,1],'FontSize',fs)
            end
            
            
        end


        function result = SetupForm(app)
            %% This function updates the form's controls based on the hardware
            result=0;
            dev=app.devinfo;
            SD=app.nSD;
            
            
            %identify wavelengths
            lambdas=cellstr(string(app.devinfo.Wavelengths));
            app.WavelengthdisplayListBox.Items=lambdas;
            app.WavelengthdisplayListBox.Value=lambdas;
            
            %% decide if source power visible
            
            % right now make state panel invisible
            app.uipanelStateSwitching.Visible=0;
            app.togglebuttonSystemCharTime.Visible=0;
            app.togglebuttonReset.Visible=0;
            
            %% decide if rate is adjustable
            if app.devinfo.AdjustableRate
                app.editRate.Enable=1;
            else
                app.editRate.Enable=0;
            end
            app.editRate.Value=dev.Rate;
            
            %% decide if gain visible
            if dev.AdjustableGain
                for k=1:32
                    app.(sprintf('editGain%i',k)).Visible=1;
                end
            else
                for k=1:32
                    app.(sprintf('editGain%i',k)).Visible=0;
                end
                app.editGainAll.Visible=0;
                app.textGainAll.Visible=0;
                app.textSetGain.Visible=0;
                app.editAutoGainLevel.Visible=0;
                app.pushbuttonSetGain.Visible=0;
                app.textAutoGainSrcDet.Visible=0;
                app.editAutoGainSrcDet.Visible=0;
                app.pushbuttonAutoGainSrcDet.Visible=0;
            end
            
            %% show or display auxiliaries and set their names
            nAux=dev.nAux;
            
            
            if ~nAux  %if one or more Aux channels exist in the hardware, make Aux panel and button visible
                app.uipanelAux.Visible=0;
                app.togglebuttonDisplayAux.Visible=0;
                
                % for the following code, I will assume that the Auxlist
                % contains a name for all the Aux channels of interest;
                % that is, if we have nAux channels but
                % length(Auxlist)<nAux, then we are not interested in all
                % hardware channels, just the ones in the Auxlist. So, if
                % the Auxlist is empty, I assume we don't care about the
                % auxiliaries. Also, the GUI is only compatible with up to
                % 8 aux channels
            else
                for k=1:8
                    if k>length(dev.auxList)
                        app.(sprintf('popupmenuAux%i',k)).Visible=0; %makes excess aux channels invisible
                        app.(sprintf('checkboxAux%i',k)).Visible=0; %makes excess aux channels invisible
                    else
                        app.(sprintf('popupmenuAux%i',k)).Visible=1; %make aux channel item box visible
                        app.(sprintf('checkboxAux%i',k)).Visible=1; %makes excess aux channels invisible
                        app.(sprintf('popupmenuAux%i',k)).Items=dev.auxList; %populate the popupmenu
                        app.(sprintf('popupmenuAux%i',k)).Value=dev.auxList{k};
                    end
                end
            end


            %% decide how many source controls to display
            %right now it assumes devices have only two wavelengths; a solution
            %will have to be found in the future. It also only supports up
            %to 16 sources and 32 detectors
            for k=1:16
                if k>SD.nSrcs
                    app.(sprintf('Button_%i',k)).Visible=0;
                    for m=1:SD.nLambdas
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Visible=0;
                    end
                else
                    %set visibility
                    app.(sprintf('Button_%i',k)).Visible=1;
                    for m=1:SD.nLambdas
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Visible=1;
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).FontSize=8;
                        %set labels
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Text=lambdas{m};
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Tag=[num2str(k),'_',num2str(m)];
                    end
                    
                end
            end
            
            
            %% decide how many detector controls to display
            
            for k=1:32
                if k>SD.nDets
                    app.(sprintf('textDet%i',k)).Visible=0;
                else
                    app.(sprintf('textDet%i',k)).Visible=1;
                end
            end
            
            
        end
        
        function [results,limis] = settimeaxis(app)
            
            %specifies how the x axis should look
            results=1;
            value = app.DisplayWindowsecondsEditField.Value;
            mint=max([0,app.currt-value]); %avoids plotting negative t
            limis=[mint,app.currt];
            %             xlim(app.axisPlotData,limis)
        end
        
        function results = setyaxis(app)
            
            % specifies how the y axis should look
            results=1;
            
            
            %app.checkboxDisplayNormalized
            %app.checkboxDisplayHbX
            
            %decide if autoscale or custom limits
            try
                if ~app.checkboxAutoscale.Value
                    ylim(app.axisPlotData,[app.yminEditField.Value,app.ymaxEditField.Value])
                else
                    ylim(app.axisPlotData,'auto')
                end
            catch
                % this is just in case limits are wrong
            end
            
        end
        
        function results = save2file(app,fname)
            %saves the data to file
            results=0;
            
            if nargin==1
                fname=['NIRS_',char(datetime('now','Format','d-MMM-y-HH-mm-ss')),'.nirs'];
            end
            
            data=app.databuffer;
            s=app.stims;
            t=app.time;
            indi=~isnan(data(1,:));  %crops the empty parts of the data buffer; it needs to be a more robust function
            t=t(indi)';
            d=data(:,indi)';
            s=s(:,indi)';
            s(isnan(s))=0;
            SD=app.nSD;
            ml=SD.measList;
            try
                save(fname,'d','s','t','SD','ml','-mat')
                results=1;
            catch
                disp('Data could not be saved')
            end
            
            
        end
        
        function results = validate_nSD(app,nSD1)
            %%function called when importing "nSD" variable from file;
            %%returns a boolean "results' indicating whether an error
            %%occurred when trying to load the contents of nSD
            
            results = 1; % Asssumed innocent until proven guilty
            
            % attempts to load all needed aspects of nSD - NEED TO KEEP
            % UPDATED
            try
                %if one of the fields is missing, an error will occur
                nSD1.measList;
                nSD1.nLambdas;
                nSD1.freqMap;
                nSD1.srcPos;
                nSD1.detPos;
                nSD1.nSrcs;
                nSD1.nDets;
            catch
                results = 0;
            end
        end
                
 
    end
    
    methods (Access = public)
        function results = SDGcallback(app,event)
            %%function called when a source, detector or channel are clicked on
            %%the SD plot; it determines how to update the selection of the
            %%graphic objects and sets a state variable currchans indicating
            %%which channel from the measurement list we want to plot
            results=1;
            evtag=event.Source.Tag; %tag of the clicked element
            ML=app.nSD.measList;
            
            objtype=evtag(1:3);
            objnum=str2double(evtag(4:end));
            
            nChans=size(ML,1)/app.nSD.nLambdas;
            
            %decide how to proceed depending on if we selected a channel, a
            %source or a detector. In case of selecting a channel, all
            %current channels but the clicked one will be deselected; if
            %the clicked one was selected already, then no channel will be
            %selected. In the case of pressing a detector, the program will
            %find what channels are measured by that source, and select all
            %of them; in the case of a source, we will find out what
            %channels are fed by that source, and select them; again, if it
            %was selected already, we will deselect everything.
            switch objtype
                case 'Cha'
                    selmeas=objnum;
                    
                    %set all channel colors to grey
                    for k=1:nChans
                        app.axesSDG.Children(end-nChans+k).Color=[0.5,0.5,0.5]; %assumes channels were drawn first
                    end
                    
                    %check if the selected channel is equal to the
                    %currently active channel
                    
                    if isequal(selmeas,app.currchans)
                        %since the selection and the current selection is
                        %the same, deselect selected channel. However, this
                        %is only allowed if the app is stopped. If it's
                        %running, it will keep it selected
                        if ~app.active
                            app.currchans=[];
                        else
                            %set the selected channel color to cyan
                            h=findobj(app.axesSDG.Children,'Tag',evtag);
                            h.Color=app.colores(1,:);
                            
                            %save which channel was selected
                            app.currchans=selmeas;
                        end
                    else
                        %set the selected channel color to cyan
                        h=findobj(app.axesSDG.Children,'Tag',evtag);
                        h.Color=app.colores(1,:);
                        
                        %save which channel was selected
                        app.currchans=selmeas;
                    end
                case 'Det'
                    selmeas=find(ML(1:nChans,2)==objnum); %find which measurements are connected to clicked detector
                    if isempty(selmeas)
                        return
                    end
                    
                    %set all channel colors to grey
                    for k=1:nChans
                        app.axesSDG.Children(end-nChans+k).Color=[0.5,0.5,0.5]; %assumes channels were drawn first
                    end
                    
                    %check if clicking on same detector as before
                    
                    if isequal(selmeas,app.currchans)
                        %if so, this means we need to deselect all
                        %channels, but only if the app is inactive
                        if ~app.active
                            app.currchans=[];
                        else
                            for k=1:length(selmeas)
                                h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                                h.Color=app.colores(k,:);
                            end
                            app.currchans=selmeas;
                        end
                        
                    else
                        %if not equal, set selected channel to selmeas, and
                        %change color of selected channels
                        for k=1:length(selmeas)
                            h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                            h.Color=app.colores(k,:);
                        end
                        app.currchans=selmeas;
                    end
                    
                case 'Src'
                    selmeas=find(ML(1:nChans,1)==objnum); %find which measurements are connected to clicked source
                    if isempty(selmeas)
                        return
                    end
                    %set all channel colors to grey
                    for k=1:nChans
                        app.axesSDG.Children(end-nChans+k).Color=[0.5,0.5,0.5];
                    end
                    
                    %check if clicking on same detector as before
                    
                    if isequal(selmeas,app.currchans)
                        %if so, this means we need to deselect all
                        %channels, but only if the app is inactive
                        if ~app.active
                            app.currchans=[];
                        else
                            for k=1:length(selmeas)
                                h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                                h.Color=app.colores(k,:);
                            end
                            app.currchans=selmeas;
                        end
                        
                    else
                        %if not equal, set selected channel to selmeas, and
                        %change color of selected channels
                        for k=1:length(selmeas)
                            h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                            h.Color=app.colores(k,:);
                        end
                        app.currchans=selmeas;
                    end
                    
            end
            %disp(app.currchans)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DeviceInfo, Environment)
            %Startup routine
            
            % define colors
            app.colores=[...
                0    0.4470    0.7410;...
                0.8500    0.3250    0.0980;...
                0.9290    0.6940    0.1250;...
                0.4940    0.1840    0.5560;...
                0.4660    0.6740    0.1880;...
                0.3010    0.7450    0.9330;...
                0.6350    0.0780    0.1840
                [1,0,0];...
                [0,1,0];...
                [0,0,1];...
                [0,1,1];...
                [1,0,1];...
                [1,1,0];...
                ];
            
            app.lineS=[':','-','-.','--'];
            
            app.fNIRScontrolUIFigure.Visible='off';
            movegui(app.fNIRScontrolUIFigure,'center')
            
            %%load configuration from input
            app.devinfo=DeviceInfo;
            app.funcSet=Environment.functions;
            app.CommSet=Environment.commands;
            app.sppars=Environment.SPparams;
            
            %% create serial port object
            %create serial port object
            
            disp(['Connecting to serial port ',app.devinfo.commPort])
            try
                
                s=instrfind;   %check if there are other serial ports
                Ns=length(s); %number of ports
                
                %% find if one of the active devices is in the desired port
                for k=1:Ns
                    % if port is already in use...
                    if strfind(s(k).Name,app.devinfo.commPort)
                        % close the port and delete it
                        fclose(s(k));
                        delete(s(k))
                    end
                    
                end
                
                app.sp=serial(app.devinfo.commPort);
                
                %find what serial parameters are specified in spars
                
                campos=fieldnames(app.sppars);
                
                %set sp parameters to specs
                for ki=1:length(campos)
                    set(app.sp,campos{ki},app.sppars.(campos{ki}));
                end
                
                fopen(app.sp);
                disp('Serial communication established!')
            catch
                %if creation goes right
                %proceed
                % %else
                %return error and close, maybe call configuration dialog again
                disp('Serial communication NOT established!')
            end
            
            %% call sd file callback
            menuLoadSDGMenuSelected(app);


            %% ask status
            app.funcSet.Ask4Status(app);
            
            %if everything went well, make main window visible
            app.fNIRScontrolUIFigure.Visible='on';
            
            
        end

        % Button pushed function: pushbuttonStimMark
        function pushbuttonStimMarkButtonPushed(app, event)
            app.stims(floor(app.currt*app.editRate.Value))=1;
        end

        % Value changed function: togglebuttonStartStop
        function acquisitionFunction(app, event)
            %             try
            % Routine for starting the acquisition
            
            if app.active
                %if it's active already, deactivate!
                
                %change button label
                app.togglebuttonStartStop.Text='START';
                
                %flag to indicate that the app is not running anymore
                app.active=0;
                
                if app.devinfo.AdjustableRate
                    app.editRate.Enable=1;
                end
            else
                %then start running, if it's not active
                
                %stop if no channel is selected
                if isempty(app.currchans)
                    app.togglebuttonStartStop.Value=0;
                    uialert(app.fNIRScontrolUIFigure,'Select a channel first','Invalid channel selection');
                    return
                end
                
                %set labels for axes
                xlabel(app.axisPlotData,'Time [s]')
                
                switch app.DisplaytypeButtonGroup.SelectedObject.Text
                    case 'dB'
                        ylabel(app.axisPlotData,'Amplitude [dB]')
                    case 'Delta OD'
                        ylabel(app.axisPlotData,'\DeltaOD')
                    case 'Hemoglobin'
                        ylabel(app.axisPlotData,'\DeltaHb []')
                end
                
                hold(app.axisPlotData,'on')
                
                %determine how long it will run
                
                
                %change button label
                app.togglebuttonStartStop.Text='STOP';
                %flag that the app is running now
                app.active=1;
                
                %do it with a loop now, later try to do with bytesavfunct
                
                
                %flush buffer
                app.funcSet.FlushBuffer(app);
                
                %start aqcquisition
                fwrite(app.sp,app.CommSet.Start(app));
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %% Code for the conversion to Hb based on the hardware wavelengths
                %Not sure if those are the same coefficients they were
                %using before
                %                 e = [ 0.9569 4.9326; 2.3214 1.7917] * 1e2;  % /mm
                %                 einv = (e'*e )\e';
                
                e=GetExtinctions(app.devinfo.Wavelengths)/10;
                e=e(:,1:2);
                einv = (e'*e )\e';
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                
                app.editRate.Enable=0;
                N_SAMPS_ACQ=0;
                N_SAMPLES_TO_ACQ=ceil(60*app.nMins*app.editRate.Value);
                
                data=nan(size(app.nSD.measList,1)+app.devinfo.nAux,N_SAMPLES_TO_ACQ);
                %datab=nan(8,N_SAMPLES_TO_ACQ,6);
                t=(0:N_SAMPLES_TO_ACQ-1)./app.editRate.Value;
                app.stims=nan*t;
                Nchans=size(app.nSD.measList,1)/app.nSD.nLambdas; %number of channels
                tic
                tp1=0;
                while app.togglebuttonStartStop.Value && N_SAMPS_ACQ<N_SAMPLES_TO_ACQ %do while button is on the position and we have not read enough samples
                    
                    if app.sp.BytesAvailable>500  %this line is there to prevent trying to read an empty buffer; the specific number should be hardware specific
                        
                        %acquire data
                        datatemp=app.funcSet.ReadBytesAvailable(app);
                        
                        %determine data package size and store in data
                        %buffer
                        packlen=size(datatemp,2);
                        N_SAMPS_ACQ=N_SAMPS_ACQ+packlen;  %number of currently acquired samples
                        app.currt=t(N_SAMPS_ACQ);
                        data(:,N_SAMPS_ACQ-packlen+1:N_SAMPS_ACQ)=datatemp;
                        
                        % stream data temp
                        app.LSLstreamer.outlet.push_chunk([datatemp;app.stims(1,N_SAMPS_ACQ-packlen+1:N_SAMPS_ACQ)]);
                        
                        %plot but only every half a second
                        
                        if toc>tp1+0.25
                            %all or at least most of this code should be sent
                            %to a drawing axes function
                            
                            %determine what wavelengths we want to visualize
                            [~,lambdai]=ismember(app.WavelengthdisplayListBox.Value,app.WavelengthdisplayListBox.Items);
                            
                            [~,limis]=settimeaxis(app);
                            limis1=floor(limis(1)*app.editRate.Value);
                            limis2=floor(limis(2)*app.editRate.Value);
                            if limis1==0
                                limis1=1;
                            end
                            
                            % indices of measurement list to display
                            MLi=app.currchans+(lambdai-1)*Nchans;
                            
                            
                            %create plot buffer
                            plotbuffer=data(MLi(:),limis1:limis2);
                            
                            switch app.DisplaytypeButtonGroup.SelectedObject.Text
                                case 'Intensity'
                                    
                                case 'dB'
                                    plotbuffer=20*log10(plotbuffer);
                                case 'Delta OD'
                                    plotbuffer=log10(plotbuffer);
                                    plotbuffer=plotbuffer-nanmean(plotbuffer,2);
                                case 'Hemoglobin'
                                    plotbuffer=log10(plotbuffer);
                                    plotbuffer=-(plotbuffer-nanmean(plotbuffer,2));
                                    plotbuffer=kron(einv,eye(length(app.currchans)))*plotbuffer; %this will break if I only plot one wavelength
                            end
                            
                            
                            %subset of time vector
                            t1=t(1,limis1:limis2);
                            
                            % plot buffer
                            %                             plot(app.axisPlotData,t1,plotbuffer);
                            
                            for li=1:length(lambdai)
                                h=plot(app.axisPlotData,t1,plotbuffer(1+(li-1)*length(app.currchans):li*length(app.currchans),:),'lineStyle',app.lineS(lambdai(li)));
                                set(h,{'color'},num2cell(app.colores(1:length(app.currchans),:),2))
                                hold(app.axisPlotData,'on')
                            end
                            hold(app.axisPlotData,'off')
                            
                            %set time axis
                            xlim(app.axisPlotData,limis)
                            
                            drawnow limitrate
                            
                            %update time of last plot
                            tp1=toc;
                        end
                    end
                    
                    
                end
                
                if app.devinfo.AdjustableRate
                    app.editRate.Enable=1;
                end
                
                % stop acquisition
                fwrite(app.sp,app.CommSet.Stop(app));
                
                % save generated data as state variable
                app.databuffer=data;
                app.time=t;
                
                % autosave to file if activeted
                if app.menu_AutoSave.Checked
                    %save
                    save2file(app);
                end
                
                %flush serial buffer
                app.funcSet.FlushBuffer(app);
                
                % change label on button
                app.togglebuttonStartStop.Text='START';
                
                % indicate if it's inactive
                app.togglebuttonStartStop.Value=0;
                app.active=0;


            end
            %             catch ME
            %                 %disp(ME)
            %                 assignin('base','ME',ME)
            %
            %             end
        end

        % Close request function: fNIRScontrolUIFigure
        function fNIRS_exit(app, event)
            
            if strcmp(app.sp.status,'open')  %if connected
                try
                    fwrite(app.sp,app.CommSet.LightsOff(app)) %turn lasers off
                    fwrite(app.sp,app.CommSet.Stop(app)) %stop acquisition
                catch
                    disp('Error when stopping device')
                end
                disp('Closing serial communication...')
                fclose(app.sp);%close serial communication
                delete(app.sp);%close serial communication
            end
            
            
            try    %to close the LSL streamer
                disp('Closing LSL stream...')
                app.LSLstreamer.outlet.delete()
            catch
                
            end
            
            disp('Bye!')
            delete(app)
            return
            
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            disp('Debug!'); %just for debugging purposes
        end

        % Menu selected function: menuLoadSDG
        function menuLoadSDGMenuSelected(app, event)
            %routine for loading the SD file and setting the system up based on
            %the file contents
            
            % call dialog to get file name
            
            disp('Please select a probe configuration file...'); % prompt user
            
            while(1) 
                [shortname, path] = uigetfile({'*.nsd','Source-detector file (.nsd)'},'Select nSD file'); % opens explorer dialogue
                escapeChoice = 2;
                
                if(shortname == 0) % will be 0 if no file selected
                    disp('No file selected.'); % prompt user
                    escapeChoice = menu('No file selected. Terminate program?', 'Yes', 'No'); % Give a loop break option                    
                end
                
                % Terminate app
                if(escapeChoice == 1)
                    disp('No probe configuration selected. Program terminated.')
                    fNIRS_exit(app);                    
                end
                
                % Return to loop start
                if(shortname == 0)
                    continue
                end
                
                fname = [path,shortname]; % Creates full file name w/ path
                loadErr = 0;
                
                % Ensure there is an 'nSD' variable in the file
                try
                    load(fname,'nSD','-mat');
                catch
                    loadErr = 1;
                    menu(strcat('That file is not compatible with the ', ...
                        'current system. Please try again.'), 'Okay')                    
                end
                
                % Verify that all needed variables are contained in nSD
                if(loadErr ~= 1)
                    if(validate_nSD(app,nSD) == 0)
                        menu(strcat('That file is not compatible with the ', ...
                        'current system. Please try again.'), 'Okay')
                        loadErr = 1;
                    end
                end
                
                % Will only exit loop once everything is alright
                if(loadErr ~= 1)
                    break
                end
            end
            
            if(escapeChoice == 1)
                    return;
            end
            
            
%             fname='mental_math.nsd';
%             
%             load(fname,'nSD','-mat');
            app.nSD=nSD; %#ok<ADPROPLC>
            
            
            %validate that hardware is correct and compatible
            
            %for example, check that the number of sources and detectors on the sd file do not exceed the ones for the hardware
            
            % initialize LED state state variable
            app.LEDstate=zeros(app.nSD.nSrcs,app.nSD.nLambdas); % Description
            
            
            %reset current observed channel
            app.currchans=[];
            
            %draw the probe in appropiate window
            drawSD(app);
            
            %set the frequencies of each channel for state 1
            app.funcSet.MapFrequencies(app,squeeze(app.nSD.freqMap(:,1,:)));
            
            %set the light levels for each source at state 1. Binary
            %assumes a given source will only be either turned on or off;
            
            if strcmp(app.devinfo.LaserPowerControl,'Binary')
                for k=1:app.nSD.nSrcs
                    for m=1:app.nSD.nLambdas
                        app.Level(k,m)=sign(app.nSD.freqMap(k,1,m));
                    end
                end
            end


            %% Initialize LSL
            
            addpath(genpath('liblsl-Matlab'))
            
            disp('Loading the library...');
            lib = lsl_loadlib();
            
            % make a new stream outlet
            disp('Creating a new streaminfo...');
            LSLchann=length(app.nSD.measList)+app.devinfo.nAux+1;
            info = lsl_streaminfo(lib,'ninjaNIRS','fNIRS',LSLchann,app.devinfo.Rate,'cf_float32','sdfwerr32432');
            
            disp('Opening an outlet...');
            outlet = lsl_outlet(info);
            
            app.LSLstreamer.lib=lib;
            app.LSLstreamer.outlet=outlet;
            
            %% update form based on environment/deviceinfo
            SetupForm(app);
            
            
        end

        % Menu selected function: menuExit
        function menuExitMenuSelected(app, event)
            %call closing callback
            fNIRS_exit(app);
        end

        % Menu selected function: menuSaveData
        function menuSaveDataMenuSelected(app, event)
       
            %save manually
            
            %call browse dialog
            [file,path] = uiputfile({'*.nirs','Matlab files (*.nirs)'},'Save fNIRS file',['NIRS_',char(datetime('now','Format','d-MMM-y-HH-mm-ss'))]);
            
            %call save function
            if file
                save2file(app, [path,file]);
            else
                save2file(app);
            end
        end

        % Button down function: fNIRScontrolUIFigure
        function fNIRScontrolUIFigureButtonDown(app, event)
            
        end

        % Value changed function: togglebuttonSrcOnOff
        function toggleSourceState(app, event)
            
            if event.Source.Value
                % We desire all lights on
                
                app.LEDstate=ones(size(app.LEDstate)); % desired state
                for k=1:size(app.Level,1)
                    fwrite(app.sp,app.CommSet.SourceNIndL(app,k,app.Level(k,:)))
                    app.(sprintf('Button_%i',k)).Value=1;
                    for m=1:size(app.Level,2)
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value=1;
                    end
                end
            else
                % We desire all lights off
                fwrite(app.sp,app.CommSet.LightsOff(app))
                for k=1:size(app.Level,1)
                    app.(sprintf('Button_%i',k)).Value=0;
                    for m=1:size(app.Level,2)
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value=0;
                    end
                end
                app.LEDstate=app.LEDstate*0; % save state of LEDs in memory
            end
        end

        % Value changed function: DisplayWindowsecondsEditField
        function DisplayWindowsecondsEditFieldValueChanged(app, event)
            %             value = app.DisplayWindowsecondsEditField.Value;
            %             mint=max([0,app.currt-value]); %avoids plotting negative t
            %             xlim(app.axisPlotData,[mint,app.currt])
            %
            settimeaxis(app)
        end

        % Value changed function: Button_1, Button_10, Button_11, 
        % Button_12, Button_13, Button_14, Button_15, Button_16, 
        % Button_2, Button_3, Button_4, Button_5, Button_6, 
        % Button_7, Button_8, Button_9
        function switchAllOnSource(app, event)
            %% change state of all wavelengths on clicked source
            
            %% read desired state of all LEDs
            for k=1:app.nSD.nSrcs
                for m=1:app.nSD.nLambdas
                    app.LEDstate(k,m)=app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value;
                end
            end
            
            srcNmb=str2double(event.Source.Text);
            
            % if button state is on, all wavelengths will be on regardless
            % of previous state. if button state is off, all wavelengths
            % will be turned on regardless of previous state
            if event.Source.Value
                % turn all on source off
                
                app.LEDstate(srcNmb,:)=1; %change state variable for this source
                % turn all on source on
                fwrite(app.sp,app.CommSet.SourceNOn(app,srcNmb,app.Level(srcNmb,1)))  %assumes both wavelengths should be at same level
                for k=1:app.devinfo.nLambda
                    app.(sprintf('togglebuttonSrc%i_%i',srcNmb,k)).Value=1;
                end
                
            else
                % turn all on source off
                
                app.LEDstate(srcNmb,:)=0; %change state variable for this source
                fwrite(app.sp,app.CommSet.SourceNOff(app,srcNmb))
                for k=1:app.devinfo.nLambda
                    app.(sprintf('togglebuttonSrc%i_%i',srcNmb,k)).Value=0;
                end
                app.togglebuttonSrcOnOff.Value=0;
            end
        end

        % Value changed function: togglebuttonSrc10_1, 
        % togglebuttonSrc10_2, togglebuttonSrc11_1, 
        % togglebuttonSrc11_2, togglebuttonSrc12_1, 
        % togglebuttonSrc12_2, togglebuttonSrc13_1, 
        % togglebuttonSrc13_2, togglebuttonSrc14_1, 
        % togglebuttonSrc14_2, togglebuttonSrc15_1, 
        % togglebuttonSrc15_2, togglebuttonSrc16_1, 
        % togglebuttonSrc16_2, togglebuttonSrc1_1, 
        % togglebuttonSrc1_2, togglebuttonSrc2_1, 
        % togglebuttonSrc2_2, togglebuttonSrc3_1, 
        % togglebuttonSrc3_2, togglebuttonSrc4_1, 
        % togglebuttonSrc4_2, togglebuttonSrc5_1, 
        % togglebuttonSrc5_2, togglebuttonSrc6_1, 
        % togglebuttonSrc6_2, togglebuttonSrc7_1, 
        % togglebuttonSrc7_2, togglebuttonSrc8_1, 
        % togglebuttonSrc8_2, togglebuttonSrc9_1, togglebuttonSrc9_2
        function switchWavelength(app, event)
            
            % Identify current desired state of all LEDs
            for k=1:app.nSD.nSrcs
                for m=1:app.nSD.nLambdas
                    app.LEDstate(k,m)=app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value;
                end
            end
            
            % Identify source that originated the event
            Tag=event.Source.Tag;
            separ=strfind(Tag,'_');
            srcNmb=str2double(Tag(1:separ-1));
            
            
            estados=zeros(1,app.nSD.nLambdas);
            
            % identify state of other LEDs on same optode
            for m=1:app.nSD.nLambdas
                estados(m)=app.(sprintf('togglebuttonSrc%i_%i',srcNmb,m)).Value;
            end
            
            %send command
            fwrite(app.sp,app.CommSet.SourceNIndL(app,srcNmb,estados.*app.Level(srcNmb,:)))
            
            % specify behavior of related buttons
            if ~event.Source.Value
                app.togglebuttonSrcOnOff.Value=0;
                app.(sprintf('Button_%i',srcNmb)).Value=0;
            end
            
        end

        % Selection changed function: DisplaytypeButtonGroup
        function DisplaytypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplaytypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'dB'
                    ylabel(app.axisPlotData,'Amplitude [dB]')
                case 'Delta OD'
                    ylabel(app.axisPlotData,'\DeltaOD')
                case 'Hemoglobin'
                    ylabel(app.axisPlotData,'\DeltaHb []')                    
            end
            
            
            if strcmp(selectedButton.Text,'Hemoglobin')
                app.WavelengthdisplayListBox.Value=app.WavelengthdisplayListBox.Items;
                app.WavelengthdisplayListBox.Enable=0; %disable wavelength selection
            else
                app.WavelengthdisplayListBox.Enable=1; %enable wavelength selection
            end
            
            
        end

        % Menu selected function: menu_AutoSave
        function menu_AutoSaveMenuSelected(app, event)
            
            % control behavior of checkmarks, as Mathworks was too lazy to
            % program it themselves
            if strcmp(app.menu_AutoSave.Checked,'on')
                app.menu_AutoSave.Checked=0;
            else
                app.menu_AutoSave.Checked=1;
            end
            
        end

        % Menu selected function: menu_DataCollection60min
        function menu_DataCollection60minMenuSelected(app, event)
            a=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fNIRScontrolUIFigure = uifigure('Visible', 'off');
            app.fNIRScontrolUIFigure.Name = 'fNIRS control';
            app.fNIRScontrolUIFigure.CloseRequestFcn = createCallbackFcn(app, @fNIRS_exit, true);
            app.fNIRScontrolUIFigure.ButtonDownFcn = createCallbackFcn(app, @fNIRScontrolUIFigureButtonDown, true);
            app.FileMenu = uimenu(app.fNIRScontrolUIFigure);
            app.FileMenu.Text = 'File';
            app.menuLoadSDG = uimenu(app.FileMenu);
            app.menuLoadSDG.MenuSelectedFcn = createCallbackFcn(app, @menuLoadSDGMenuSelected, true);
            app.menuLoadSDG.Text = 'Load SDG';
            app.menuSaveSDG = uimenu(app.FileMenu);
            app.menuSaveSDG.Visible = 'off';
            app.menuSaveSDG.Enable = 'off';
            app.menuSaveSDG.Text = 'Save SDG';
            app.menuSaveData = uimenu(app.FileMenu);
            app.menuSaveData.MenuSelectedFcn = createCallbackFcn(app, @menuSaveDataMenuSelected, true);
            app.menuSaveData.Separator = 'on';
            app.menuSaveData.Text = 'Save data';
            app.menuExit = uimenu(app.FileMenu);
            app.menuExit.MenuSelectedFcn = createCallbackFcn(app, @menuExitMenuSelected, true);
            app.menuExit.Separator = 'on';
            app.menuExit.Text = 'Exit';
            app.OptionsMenu = uimenu(app.fNIRScontrolUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.menu_AutoSave = uimenu(app.OptionsMenu);
            app.menu_AutoSave.MenuSelectedFcn = createCallbackFcn(app, @menu_AutoSaveMenuSelected, true);
            app.menu_AutoSave.Checked = 'on';
            app.menu_AutoSave.Text = 'Auto Save Data Files';
            app.menu_AutoSaveDirectory = uimenu(app.OptionsMenu);
            app.menu_AutoSaveDirectory.Enable = 'off';
            app.menu_AutoSaveDirectory.Text = 'Auto Save Directory';
            app.menu_DataCollectionPeriod = uimenu(app.OptionsMenu);
            app.menu_DataCollectionPeriod.Enable = 'off';
            app.menu_DataCollectionPeriod.Text = 'Data Collection Period';
            app.menu_DataCollection60min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection60min.MenuSelectedFcn = createCallbackFcn(app, @menu_DataCollection60minMenuSelected, true);
            app.menu_DataCollection60min.Checked = 'on';
            app.menu_DataCollection60min.Text = '60 min';
            app.menu_DataCollection120min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection120min.Text = '120 min';
            app.menu_DataCollection180min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection180min.Text = '180 min';
            app.menu_DataCollection15minAuto = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection15minAuto.Text = '15 min Autosave';
            app.menu_DataCollection30minAuto = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection30minAuto.Text = '30 min Autosave';
            app.menu_DataCollection60minAuto = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection60minAuto.Text = '60 min Autosave';
            app.menuSDGdisplaySignalLevel = uimenu(app.OptionsMenu);
            app.menuSDGdisplaySignalLevel.Enable = 'off';
            app.menuSDGdisplaySignalLevel.Text = 'SDG display signal level';
            app.menuSDGdisplaySignalLevel_Threshold = uimenu(app.menuSDGdisplaySignalLevel);
            app.menuSDGdisplaySignalLevel_Threshold.Checked = 'on';
            app.menuSDGdisplaySignalLevel_Threshold.Text = 'Threshold';
            app.menuSDGdisplaySignalLevel_Analog = uimenu(app.menuSDGdisplaySignalLevel);
            app.menuSDGdisplaySignalLevel_Analog.Text = 'Analog';
            app.menuStartKeypress = uimenu(app.OptionsMenu);
            app.menuStartKeypress.Enable = 'off';
            app.menuStartKeypress.Text = 'Start with Key Press';
            app.menuStateSwitching = uimenu(app.OptionsMenu);
            app.menuStateSwitching.Enable = 'off';
            app.menuStateSwitching.Text = 'State Switching';
            app.menuLaserLinearityCalibration = uimenu(app.OptionsMenu);
            app.menuLaserLinearityCalibration.Enable = 'off';
            app.menuLaserLinearityCalibration.Text = 'Laser Linearity Calibration';
            app.menuSystemCharacterization = uimenu(app.OptionsMenu);
            app.menuSystemCharacterization.Enable = 'off';
            app.menuSystemCharacterization.Text = 'System Characterization';
            app.axisPlotData = uiaxes(app.fNIRScontrolUIFigure);
            xlabel(app.axisPlotData, 'Time [s]')
            ylabel(app.axisPlotData, 'Amplitude [dB]')
            app.axesSDG = uiaxes(app.fNIRScontrolUIFigure);
            app.togglebuttonStartStop = uibutton(app.fNIRScontrolUIFigure, 'state');
            app.togglebuttonStartStop.ValueChangedFcn = createCallbackFcn(app, @acquisitionFunction, true);
            app.togglebuttonStartStop.Text = 'START';
            app.editStdThresh = uieditfield(app.fNIRScontrolUIFigure, 'text');
            app.editStdThresh.Visible = 'off';
            app.editStdThresh.Value = '0.05';
            app.textNoiseThresh = uilabel(app.fNIRScontrolUIFigure);
            app.textNoiseThresh.Visible = 'off';
            app.textNoiseThresh.Text = 'Noise Thresh';
            app.uipanelSources = uipanel(app.fNIRScontrolUIFigure);
            app.uipanelSources.Title = 'SOURCES';
            app.togglebuttonSrc5_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc5_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc5_1.Text = '1';
            app.togglebuttonSrc5_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc5_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc5_2.Text = '2';
            app.togglebuttonSrc6_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc6_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc6_1.Text = '1';
            app.togglebuttonSrc6_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc6_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc6_2.Text = '2';
            app.togglebuttonSrc7_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc7_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc7_1.Text = '1';
            app.togglebuttonSrc7_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc7_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc7_2.Text = '2';
            app.togglebuttonSrc8_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc8_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc8_1.Text = '1';
            app.togglebuttonSrc8_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc8_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc8_2.Text = '2';
            app.togglebuttonSrc1_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc1_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc1_1.Text = '1';
            app.togglebuttonSrc1_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc1_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc1_2.Text = '2';
            app.togglebuttonSrc2_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc2_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc2_1.Text = '1';
            app.togglebuttonSrc2_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc2_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc2_2.Text = '2';
            app.togglebuttonSrc3_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc3_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc3_1.Text = '1';
            app.togglebuttonSrc3_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc3_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc3_2.Text = '2';
            app.togglebuttonSrc4_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc4_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc4_1.Text = '1';
            app.togglebuttonSrc4_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc4_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc4_2.Text = '2';
            app.togglebuttonSrc13_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc13_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc13_1.Text = '1';
            app.togglebuttonSrc13_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc13_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc13_2.Text = '2';
            app.togglebuttonSrc14_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc14_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc14_1.Text = '1';
            app.togglebuttonSrc14_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc14_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc14_2.Text = '2';
            app.togglebuttonSrc15_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc15_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc15_1.Text = '1';
            app.togglebuttonSrc15_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc15_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc15_2.Text = '2';
            app.togglebuttonSrc16_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc16_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc16_1.Text = '1';
            app.togglebuttonSrc16_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc16_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc16_2.Text = '2';
            app.togglebuttonSrc9_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc9_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc9_1.Text = '1';
            app.togglebuttonSrc9_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc9_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc9_2.Text = '2';
            app.togglebuttonSrc10_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc10_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc10_1.Text = '1';
            app.togglebuttonSrc10_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc10_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc10_2.Text = '2';
            app.togglebuttonSrc11_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc11_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc11_1.Text = '1';
            app.togglebuttonSrc11_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc11_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc11_2.Text = '2';
            app.togglebuttonSrc12_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc12_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc12_1.Text = '1';
            app.togglebuttonSrc12_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc12_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc12_2.Text = '2';
            app.togglebuttonSrcOnOff = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrcOnOff.ValueChangedFcn = createCallbackFcn(app, @toggleSourceState, true);
            app.togglebuttonSrcOnOff.Text = 'All On/Off';
            app.editSrcPower1 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower1.Visible = 'off';
            app.editSrcPower1.Value = '10';
            app.editSrcPower3 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower3.Visible = 'off';
            app.editSrcPower3.Value = '10';
            app.editSrcPower5 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower5.Visible = 'off';
            app.editSrcPower5.Value = '10';
            app.editSrcPower7 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower7.Visible = 'off';
            app.editSrcPower7.Value = '10';
            app.editSrcPower9 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower9.Visible = 'off';
            app.editSrcPower9.Value = '10';
            app.editSrcPower11 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower11.Visible = 'off';
            app.editSrcPower11.Value = '10';
            app.editSrcPower13 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower13.Visible = 'off';
            app.editSrcPower13.Value = '10';
            app.editSrcPower15 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower15.Visible = 'off';
            app.editSrcPower15.Value = '10';
            app.editSrcPower2 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower2.Visible = 'off';
            app.editSrcPower2.Value = '10';
            app.editSrcPower4 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower4.Visible = 'off';
            app.editSrcPower4.Value = '10';
            app.editSrcPower6 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower6.Visible = 'off';
            app.editSrcPower6.Value = '10';
            app.editSrcPower8 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower8.Visible = 'off';
            app.editSrcPower8.Value = '10';
            app.editSrcPower10 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower10.Visible = 'off';
            app.editSrcPower10.Value = '10';
            app.editSrcPower12 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower12.Visible = 'off';
            app.editSrcPower12.Value = '10';
            app.editSrcPower14 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower14.Visible = 'off';
            app.editSrcPower14.Value = '10';
            app.editSrcPower16 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower16.Visible = 'off';
            app.editSrcPower16.Value = '10';
            app.editSrcPower17 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower17.Visible = 'off';
            app.editSrcPower17.Value = '10';
            app.editSrcPower19 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower19.Visible = 'off';
            app.editSrcPower19.Value = '10';
            app.editSrcPower21 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower21.Visible = 'off';
            app.editSrcPower21.Value = '10';
            app.editSrcPower23 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower23.Visible = 'off';
            app.editSrcPower23.Value = '10';
            app.editSrcPower25 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower25.Visible = 'off';
            app.editSrcPower25.Value = '10';
            app.editSrcPower27 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower27.Visible = 'off';
            app.editSrcPower27.Value = '10';
            app.editSrcPower29 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower29.Visible = 'off';
            app.editSrcPower29.Value = '10';
            app.editSrcPower31 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower31.Visible = 'off';
            app.editSrcPower31.Value = '10';
            app.editSrcPower18 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower18.Visible = 'off';
            app.editSrcPower18.Value = '10';
            app.editSrcPower20 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower20.Visible = 'off';
            app.editSrcPower20.Value = '10';
            app.editSrcPower22 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower22.Visible = 'off';
            app.editSrcPower22.Value = '10';
            app.editSrcPower24 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower24.Visible = 'off';
            app.editSrcPower24.Value = '10';
            app.editSrcPower26 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower26.Visible = 'off';
            app.editSrcPower26.Value = '10';
            app.editSrcPower28 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower28.Visible = 'off';
            app.editSrcPower28.Value = '10';
            app.editSrcPower30 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower30.Visible = 'off';
            app.editSrcPower30.Value = '10';
            app.editSrcPower32 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower32.Visible = 'off';
            app.editSrcPower32.Value = '10';
            app.textSetSrcPower = uilabel(app.uipanelSources);
            app.textSetSrcPower.Visible = 'off';
            app.textSetSrcPower.Text = 'Auto Gain Src';
            app.editSetSrcPower = uieditfield(app.uipanelSources, 'text');
            app.editSetSrcPower.Visible = 'off';
            app.editSetSrcPower.Value = '90';
            app.pushbuttonSetSrcPower = uibutton(app.uipanelSources, 'push');
            app.pushbuttonSetSrcPower.Enable = 'off';
            app.pushbuttonSetSrcPower.Visible = 'off';
            app.pushbuttonSetSrcPower.Text = 'Set Gain';
            app.editSetSrcPowerAll = uieditfield(app.uipanelSources, 'text');
            app.editSetSrcPowerAll.Visible = 'off';
            app.editSetSrcPowerAll.Value = '50';
            app.textSetSrcPowerAll = uilabel(app.uipanelSources);
            app.textSetSrcPowerAll.Visible = 'off';
            app.textSetSrcPowerAll.Text = 'Set All Gains';
            app.Button_1 = uibutton(app.uipanelSources, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.uipanelSources, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.uipanelSources, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.uipanelSources, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.uipanelSources, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.uipanelSources, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.uipanelSources, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.uipanelSources, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.uipanelSources, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.uipanelSources, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_10.Text = '10';
            app.Button_11 = uibutton(app.uipanelSources, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_11.Text = '11';
            app.Button_12 = uibutton(app.uipanelSources, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_12.Text = '12';
            app.Button_13 = uibutton(app.uipanelSources, 'state');
            app.Button_13.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_13.Text = '13';
            app.Button_14 = uibutton(app.uipanelSources, 'state');
            app.Button_14.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_14.Text = '14';
            app.Button_15 = uibutton(app.uipanelSources, 'state');
            app.Button_15.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_15.Text = '15';
            app.Button_16 = uibutton(app.uipanelSources, 'state');
            app.Button_16.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_16.Text = '16';
            app.uipanelDetectors = uipanel(app.fNIRScontrolUIFigure);
            app.uipanelDetectors.Title = 'DETECTORS';
            app.editGainAll = uieditfield(app.uipanelDetectors, 'text');
            app.editGainAll.Value = '1';
            app.textGainAll = uilabel(app.uipanelDetectors);
            app.textGainAll.Text = 'Set All Gains';
            app.textDet1 = uilabel(app.uipanelDetectors);
            app.textDet1.Text = 'D1';
            app.textDet2 = uilabel(app.uipanelDetectors);
            app.textDet2.Text = 'D2';
            app.textDet3 = uilabel(app.uipanelDetectors);
            app.textDet3.Text = 'D3';
            app.textDet4 = uilabel(app.uipanelDetectors);
            app.textDet4.Text = 'D4';
            app.textDet5 = uilabel(app.uipanelDetectors);
            app.textDet5.Text = 'D5';
            app.textDet6 = uilabel(app.uipanelDetectors);
            app.textDet6.Text = 'D6';
            app.textDet7 = uilabel(app.uipanelDetectors);
            app.textDet7.Text = 'D7';
            app.textDet8 = uilabel(app.uipanelDetectors);
            app.textDet8.Text = 'D8';
            app.editGain1 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain1.Value = '1';
            app.editGain2 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain2.Value = '1';
            app.editGain3 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain3.Value = '1';
            app.editGain4 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain4.Value = '1';
            app.editGain5 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain5.Value = '1';
            app.editGain6 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain6.Value = '1';
            app.editGain7 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain7.Value = '1';
            app.textDet9 = uilabel(app.uipanelDetectors);
            app.textDet9.Text = 'D9';
            app.textDet10 = uilabel(app.uipanelDetectors);
            app.textDet10.Text = 'D10';
            app.textDet11 = uilabel(app.uipanelDetectors);
            app.textDet11.Text = 'D11';
            app.textDet12 = uilabel(app.uipanelDetectors);
            app.textDet12.Text = 'D12';
            app.textDet13 = uilabel(app.uipanelDetectors);
            app.textDet13.Text = 'D13';
            app.textDet14 = uilabel(app.uipanelDetectors);
            app.textDet14.Text = 'D14';
            app.textDet15 = uilabel(app.uipanelDetectors);
            app.textDet15.Text = 'D15';
            app.textDet16 = uilabel(app.uipanelDetectors);
            app.textDet16.Text = 'D16';
            app.editGain9 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain9.Value = '1';
            app.editGain10 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain10.Value = '1';
            app.editGain11 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain11.Value = '1';
            app.editGain12 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain12.Value = '1';
            app.editGain13 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain13.Value = '1';
            app.editGain14 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain14.Value = '1';
            app.editGain15 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain15.Value = '1';
            app.editGain16 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain16.Value = '1';
            app.editGain8 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain8.Value = '1';
            app.textDet17 = uilabel(app.uipanelDetectors);
            app.textDet17.Text = 'D17';
            app.textDet18 = uilabel(app.uipanelDetectors);
            app.textDet18.Text = 'D18';
            app.textDet19 = uilabel(app.uipanelDetectors);
            app.textDet19.Text = 'D19';
            app.textDet20 = uilabel(app.uipanelDetectors);
            app.textDet20.Text = 'D20';
            app.textDet21 = uilabel(app.uipanelDetectors);
            app.textDet21.Text = 'D21';
            app.textDet22 = uilabel(app.uipanelDetectors);
            app.textDet22.Text = 'D22';
            app.textDet23 = uilabel(app.uipanelDetectors);
            app.textDet23.Text = 'D23';
            app.textDet24 = uilabel(app.uipanelDetectors);
            app.textDet24.Text = 'D24';
            app.editGain17 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain17.Value = '1';
            app.editGain18 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain18.Value = '1';
            app.editGain19 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain19.Value = '1';
            app.editGain20 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain20.Value = '1';
            app.editGain21 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain21.Value = '1';
            app.editGain22 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain22.Value = '1';
            app.editGain23 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain23.Value = '1';
            app.textDet25 = uilabel(app.uipanelDetectors);
            app.textDet25.Text = 'D25';
            app.textDet26 = uilabel(app.uipanelDetectors);
            app.textDet26.Text = 'D26';
            app.textDet27 = uilabel(app.uipanelDetectors);
            app.textDet27.Text = 'D27';
            app.textDet28 = uilabel(app.uipanelDetectors);
            app.textDet28.Text = 'D28';
            app.textDet29 = uilabel(app.uipanelDetectors);
            app.textDet29.Text = 'D29';
            app.textDet30 = uilabel(app.uipanelDetectors);
            app.textDet30.Text = 'D30';
            app.textDet31 = uilabel(app.uipanelDetectors);
            app.textDet31.Text = 'D31';
            app.textDet32 = uilabel(app.uipanelDetectors);
            app.textDet32.Text = 'D32';
            app.editGain25 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain25.Value = '1';
            app.editGain26 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain26.Value = '1';
            app.editGain27 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain27.Value = '1';
            app.editGain28 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain28.Value = '1';
            app.editGain29 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain29.Value = '1';
            app.editGain30 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain30.Value = '1';
            app.editGain31 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain31.Value = '1';
            app.editGain32 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain32.Value = '1';
            app.editGain24 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain24.Value = '1';
            app.textSetGain = uilabel(app.uipanelDetectors);
            app.textSetGain.Text = 'Auto Gain Det';
            app.editAutoGainLevel = uieditfield(app.uipanelDetectors, 'text');
            app.editAutoGainLevel.Value = '120';
            app.pushbuttonSetGain = uibutton(app.uipanelDetectors, 'push');
            app.pushbuttonSetGain.Enable = 'off';
            app.pushbuttonSetGain.Text = 'Set Gain';
            app.pushbuttonCheckSaturation = uibutton(app.uipanelDetectors, 'push');
            app.pushbuttonCheckSaturation.Enable = 'off';
            app.pushbuttonCheckSaturation.Text = 'Check Saturation';
            app.togglebuttonSystemCharacterize = uibutton(app.fNIRScontrolUIFigure, 'state');
            app.togglebuttonSystemCharacterize.Visible = 'off';
            app.togglebuttonSystemCharacterize.Text = 'START Characterization';
            app.togglebuttonReset = uibutton(app.fNIRScontrolUIFigure, 'push');
            app.togglebuttonReset.Text = 'Reset Connection';
            app.uipanelStateSwitching = uibuttongroup(app.fNIRScontrolUIFigure);
            app.uipanelStateSwitching.Title = 'STATE SWITCHING';
            app.checkboxSrcSwitchEnable = uicheckbox(app.uipanelStateSwitching);
            app.checkboxSrcSwitchEnable.Text = 'Enable';
            app.radiobuttonState3 = uiradiobutton(app.uipanelStateSwitching);
            app.radiobuttonState3.Text = 'State 3';
            app.radiobuttonState1 = uiradiobutton(app.uipanelStateSwitching);
            app.radiobuttonState1.Text = 'State 1';
            app.radiobuttonState1.Value = true;
            app.radiobuttonState2 = uiradiobutton(app.uipanelStateSwitching);
            app.radiobuttonState2.Text = 'State 2';
            app.editSrcSwitchDwell1 = uieditfield(app.uipanelStateSwitching, 'text');
            app.editSrcSwitchDwell1.Value = '10';
            app.editSrcSwitchDwell2 = uieditfield(app.uipanelStateSwitching, 'text');
            app.editSrcSwitchDwell2.Value = '10';
            app.editSrcSwitchDwell3 = uieditfield(app.uipanelStateSwitching, 'text');
            app.editSrcSwitchDwell3.Value = '10';
            app.textSrcSwitchDwell = uilabel(app.uipanelStateSwitching);
            app.textSrcSwitchDwell.Text = 'Dwell (#rec)';
            app.pushbuttonLoadStates = uibutton(app.uipanelStateSwitching, 'push');
            app.pushbuttonLoadStates.Text = 'Load State';
            app.uipanel5 = uipanel(app.fNIRScontrolUIFigure);
            app.uipanel5.Title = 'DISPLAY';
            app.togglebuttonDisplayAux = uibutton(app.uipanel5, 'state');
            app.togglebuttonDisplayAux.Text = 'Display Aux';
            app.WavelengthdisplayListBoxLabel = uilabel(app.uipanel5);
            app.WavelengthdisplayListBoxLabel.Text = {'Wavelength'; 'display'};
            app.WavelengthdisplayListBox = uilistbox(app.uipanel5);
            app.WavelengthdisplayListBox.Items = {};
            app.WavelengthdisplayListBox.Multiselect = 'on';
            app.WavelengthdisplayListBox.Value = {};
            app.DisplayWindowsecondsEditFieldLabel = uilabel(app.uipanel5);
            app.DisplayWindowsecondsEditFieldLabel.Text = 'Display Window (seconds)';
            app.DisplayWindowsecondsEditField = uieditfield(app.uipanel5, 'numeric');
            app.DisplayWindowsecondsEditField.Limits = [0 300];
            app.DisplayWindowsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayWindowsecondsEditFieldValueChanged, true);
            app.DisplayWindowsecondsEditField.Value = 10;
            app.yminEditFieldLabel = uilabel(app.uipanel5);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.uipanel5, 'numeric');
            app.yminEditField.Value = 20;
            app.ymaxEditFieldLabel = uilabel(app.uipanel5);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.uipanel5, 'numeric');
            app.ymaxEditField.Value = 100;
            app.checkboxAutoscale = uicheckbox(app.uipanel5);
            app.checkboxAutoscale.Text = 'Autoscale y';
            app.checkboxAutoscale.Value = true;
            app.DisplaytypeButtonGroup = uibuttongroup(app.uipanel5);
            app.DisplaytypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaytypeButtonGroupSelectionChanged, true);
            app.DisplaytypeButtonGroup.Title = 'Display type';
            app.dBButton = uiradiobutton(app.DisplaytypeButtonGroup);
            app.dBButton.Text = 'dB';
            app.dBButton.Value = true;
            app.DeltaODButton = uiradiobutton(app.DisplaytypeButtonGroup);
            app.DeltaODButton.Text = 'Delta OD';
            app.HemoglobinButton = uiradiobutton(app.DisplaytypeButtonGroup);
            app.HemoglobinButton.Text = 'Hemoglobin';
            app.uipanelAux = uipanel(app.fNIRScontrolUIFigure);
            app.uipanelAux.Title = 'AUXILIARIES';
            app.checkboxAux1 = uicheckbox(app.uipanelAux);
            app.checkboxAux1.Text = '';
            app.checkboxAux2 = uicheckbox(app.uipanelAux);
            app.checkboxAux2.Text = '';
            app.checkboxAux3 = uicheckbox(app.uipanelAux);
            app.checkboxAux3.Text = '';
            app.checkboxAux4 = uicheckbox(app.uipanelAux);
            app.checkboxAux4.Text = '';
            app.checkboxAux5 = uicheckbox(app.uipanelAux);
            app.checkboxAux5.Text = '';
            app.checkboxAux6 = uicheckbox(app.uipanelAux);
            app.checkboxAux6.Text = '';
            app.checkboxAux7 = uicheckbox(app.uipanelAux);
            app.checkboxAux7.Text = '';
            app.checkboxAux8 = uicheckbox(app.uipanelAux);
            app.checkboxAux8.Text = '';
            app.popupmenuAux8 = uidropdown(app.uipanelAux);
            app.popupmenuAux8.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux8.Value = 'Pulse Ox raw';
            app.popupmenuAux7 = uidropdown(app.uipanelAux);
            app.popupmenuAux7.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux7.Value = 'Pulse Ox raw';
            app.popupmenuAux6 = uidropdown(app.uipanelAux);
            app.popupmenuAux6.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux6.Value = 'Pulse Ox raw';
            app.popupmenuAux5 = uidropdown(app.uipanelAux);
            app.popupmenuAux5.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux5.Value = 'Pulse Ox raw';
            app.popupmenuAux4 = uidropdown(app.uipanelAux);
            app.popupmenuAux4.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux4.Value = 'Pulse Ox raw';
            app.popupmenuAux3 = uidropdown(app.uipanelAux);
            app.popupmenuAux3.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux3.Value = 'Pulse Ox raw';
            app.popupmenuAux2 = uidropdown(app.uipanelAux);
            app.popupmenuAux2.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux2.Value = 'Pulse Ox raw';
            app.popupmenuAux1 = uidropdown(app.uipanelAux);
            app.popupmenuAux1.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux1.Value = 'Pulse Ox raw';
            app.togglebuttonSystemCharTime = uibutton(app.fNIRScontrolUIFigure, 'state');
            app.togglebuttonSystemCharTime.Text = 'START Charact. Time';
            app.pushbuttonStimMark = uibutton(app.fNIRScontrolUIFigure, 'push');
            app.pushbuttonStimMark.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStimMarkButtonPushed, true);
            app.pushbuttonStimMark.Text = 'Stim Mark';
            app.textAutoGainSrcDet = uilabel(app.fNIRScontrolUIFigure);
            app.textAutoGainSrcDet.Text = 'Auto Gain Src/Det';
            app.editAutoGainSrcDet = uieditfield(app.fNIRScontrolUIFigure, 'text');
            app.editAutoGainSrcDet.Value = '120';
            app.pushbuttonAutoGainSrcDet = uibutton(app.fNIRScontrolUIFigure, 'push');
            app.pushbuttonAutoGainSrcDet.Enable = 'off';
            app.pushbuttonAutoGainSrcDet.Text = 'Set Gain';
            app.textMessage = uilabel(app.fNIRScontrolUIFigure);
            app.textMessage.Text = '';
            app.textSDGfilename = uilabel(app.fNIRScontrolUIFigure);
            app.textSDGfilename.Text = '';
            app.DebugButton = uibutton(app.fNIRScontrolUIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.RateEditFieldLabel = uilabel(app.fNIRScontrolUIFigure);
            app.RateEditFieldLabel.Text = 'Rate';
            app.editRate = uieditfield(app.fNIRScontrolUIFigure, 'numeric');
            app.editRate.Limits = [0 Inf];
            app.editRate.Value = 25;
            % Show the figure after all components are created
            app.fNIRScontrolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fNIRSapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fNIRScontrolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fNIRScontrolUIFigure)
        end
    end
end